classdef GUIversuchzwei < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        StartButton          matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        ScanButton           matlab.ui.control.Button
        ClumsyGoldfishLabel  matlab.ui.control.Label
        SollStatusLabel      matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        DropDown             matlab.ui.control.DropDown
        DropDown2Label       matlab.ui.control.Label
        DropDown2            matlab.ui.control.DropDown
        DropDown3            matlab.ui.control.DropDown
        DropDown4Label       matlab.ui.control.Label
        DropDown4            matlab.ui.control.DropDown
        DropDown5Label       matlab.ui.control.Label
        DropDown5            matlab.ui.control.DropDown
        DropDown6            matlab.ui.control.DropDown
        DropDown7Label       matlab.ui.control.Label
        DropDown7            matlab.ui.control.DropDown
        DropDown8Label       matlab.ui.control.Label
        DropDown8            matlab.ui.control.DropDown
        DropDown9            matlab.ui.control.DropDown
        ResumeButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        vRob=VirtualRobot('random'); % Start des vRob "random"
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global A
            global text
            A = tauschvorgang(A,app.vRob)
            app.TextArea.Value=text
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            global A
            global ii
            A=[-1 -1;-1 -1;-1 -1;-1 -1;-1 -1;-1 -1;-1 -1;-1 -1;-1 -1]
            A = scanAll(A,app.vRob)
            ii=1
        end

        % Value changed function: DropDown9
        function DropDown9ValueChanged(app, event)
            zw = app.DropDown9.Value;
            global A
            if zw == 48
                A(9,2)=0
            elseif zw == 49
                A(9,2)=1
            elseif zw == 50
                A(9,2)=2
            else
                A(9,2)=3
            end
        end

        % Value changed function: DropDown8
        function DropDown8ValueChanged(app, event)
            zw = app.DropDown8.Value;
            global A
            if zw == 48
                A(8,2)=0
            elseif zw == 49
                A(8,2)=1
            elseif zw == 50
                A(8,2)=2
            else
                A(8,2)=3
            end
        end

        % Value changed function: DropDown7
        function DropDown7ValueChanged(app, event)
            zw = app.DropDown7.Value;
            global A
            if zw == 48
                A(7,2)=0
            elseif zw == 49
                A(7,2)=1
            elseif zw == 50
                A(7,2)=2
            else
                A(7,2)=3
            end
        end

        % Value changed function: DropDown6
        function DropDown6ValueChanged(app, event)
            zw = app.DropDown6.Value;
            global A
            if zw == 48
                A(6,2)=0
            elseif zw == 49
                A(6,2)=1
            elseif zw == 50
                A(6,2)=2
            else
                A(6,2)=3
            end
        end

        % Value changed function: DropDown5
        function DropDown5ValueChanged(app, event)
            zw = app.DropDown5.Value;
            global A
            if zw == 48
                A(5,2)=0
            elseif zw == 49
                A(5,2)=1
            elseif zw == 50
                A(5,2)=2
            else
                A(5,2)=3
            end
        end

        % Value changed function: DropDown4
        function DropDown4ValueChanged(app, event)
            zw = app.DropDown4.Value;
            global A
            if zw == 48
                A(4,2)=0
            elseif zw == 49
                A(4,2)=1
            elseif zw == 50
                A(4,2)=2
            else
                A(4,2)=3
            end
        end

        % Value changed function: DropDown3
        function DropDown3ValueChanged(app, event)
            zw = app.DropDown3.Value;
            global A
            if zw == 48
                A(3,2)=0
            elseif zw == 49
                A(3,2)=1
            elseif zw == 50
                A(3,2)=2
            else
                A(3,2)=3
            end
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            zw = app.DropDown2.Value;
            global A
            if zw == 48
                A(2,2)=0
            elseif zw == 49
                A(2,2)=1
            elseif zw == 50
                A(2,2)=2
            else
                A(2,2)=3
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            zw = app.DropDown.Value;
            global A
            if zw == 48
                A(1,2)=0
            elseif zw == 49
                A(1,2)=1
            elseif zw == 50
                A(1,2)=2
            else
                A(1,2)=3
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            uiwait
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            uiresume
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '576d079b1fe1e_mb.jpg';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = {'Scan'; ''};
            app.ClumsyGoldfishLabel = uilabel(app.UIFigure);
            app.ClumsyGoldfishLabel.Text = 'ClumsyGoldfish';
            app.SollStatusLabel = uilabel(app.UIFigure);
            app.SollStatusLabel.Text = 'Soll-Status';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'?', 'Rot', 'Gruen', 'Blau'};
            app.DropDown.ItemsData = {'50', '0', '1', '2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '50';
            app.DropDown2Label = uilabel(app.UIFigure);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'?', 'Rot', 'Gruen', 'Blau'};
            app.DropDown2.ItemsData = {'50', '0', '1', '2'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = '50';
            app.DropDown3 = uidropdown(app.UIFigure);
            app.DropDown3.Items = {'?', 'Rot', 'Gruen', 'Blau'};
            app.DropDown3.ItemsData = {'50', '0', '1', '2'};
            app.DropDown3.ValueChangedFcn = createCallbackFcn(app, @DropDown3ValueChanged, true);
            app.DropDown3.Value = '50';
            app.DropDown4Label = uilabel(app.UIFigure);
            app.DropDown4Label.Text = 'Drop Down4';
            app.DropDown4 = uidropdown(app.UIFigure);
            app.DropDown4.Items = {'?', 'Rot', 'Gruen', 'Blau'};
            app.DropDown4.ItemsData = {'50', '0', '1', '2'};
            app.DropDown4.ValueChangedFcn = createCallbackFcn(app, @DropDown4ValueChanged, true);
            app.DropDown4.Value = '50';
            app.DropDown5Label = uilabel(app.UIFigure);
            app.DropDown5Label.Text = 'Drop Down5';
            app.DropDown5 = uidropdown(app.UIFigure);
            app.DropDown5.Items = {'?', 'Rot', 'Gruen', 'Blau'};
            app.DropDown5.ItemsData = {'50', '0', '1', '2'};
            app.DropDown5.ValueChangedFcn = createCallbackFcn(app, @DropDown5ValueChanged, true);
            app.DropDown5.Value = '50';
            app.DropDown6 = uidropdown(app.UIFigure);
            app.DropDown6.Items = {'?', 'Rot', 'Gruen', 'Blau'};
            app.DropDown6.ItemsData = {'50', '0', '1', '2'};
            app.DropDown6.ValueChangedFcn = createCallbackFcn(app, @DropDown6ValueChanged, true);
            app.DropDown6.Value = '50';
            app.DropDown7Label = uilabel(app.UIFigure);
            app.DropDown7Label.Text = 'Drop Down7';
            app.DropDown7 = uidropdown(app.UIFigure);
            app.DropDown7.Items = {'?', 'Rot', 'Gruen', 'Blau'};
            app.DropDown7.ItemsData = {'50', '0', '1', '2'};
            app.DropDown7.ValueChangedFcn = createCallbackFcn(app, @DropDown7ValueChanged, true);
            app.DropDown7.Value = '50';
            app.DropDown8Label = uilabel(app.UIFigure);
            app.DropDown8Label.Text = 'Drop Down8';
            app.DropDown8 = uidropdown(app.UIFigure);
            app.DropDown8.Items = {'?', 'Rot', 'Gruen', 'Blau'};
            app.DropDown8.ItemsData = {'50', '0', '1', '2'};
            app.DropDown8.ValueChangedFcn = createCallbackFcn(app, @DropDown8ValueChanged, true);
            app.DropDown8.Value = '50';
            app.DropDown9 = uidropdown(app.UIFigure);
            app.DropDown9.Items = {'?', 'Rot', 'Gruen', 'Blau'};
            app.DropDown9.ItemsData = {'50', '0', '1', '2'};
            app.DropDown9.ValueChangedFcn = createCallbackFcn(app, @DropDown9ValueChanged, true);
            app.DropDown9.Value = '50';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIversuchzwei

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end